classdef AirQuality < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AirQualityAppUIFigure        matlab.ui.Figure
        AirQualityConditionsLabel    matlab.ui.control.Label
        AirQualityLabel              matlab.ui.control.Label
        DayForecastPanel             matlab.ui.container.Panel
        CurrentConditionsPanel       matlab.ui.container.Panel
        LocationCityBostonEditFieldLabel  matlab.ui.control.Label
        LocationCityBostonEditField  matlab.ui.control.EditField
        UpdateButton                 matlab.ui.control.StateButton
        UITable                      matlab.ui.control.Table
    end

    
    properties (Access = private)
        Label       % Air quality label
        currentData
        forecastData
    end
    
    methods (Access = private)
        
        
        function plotForecast(app)
            % Plot all weather forecast variables
            stackedplot(app.DayForecastPanel,app.forecastData)
        end
        
        function resetAppVisuals(app)
            % Reset visuals while searching and calculating
            if ~isempty(app.DayForecastPanel.Children)
                delete(app.DayForecastPanel.Children);
            end
            app.AirQualityLabel.BackgroundColor = [0.94 0.94 0.94];
            app.AirQualityLabel.Text = "Air Quality:";
            app.UITable.Visible = "off";
        end
        
        function LabelColor(app)
            % Update background color based on air quality label
            switch app.Label
                case "Good"
                    app.AirQualityLabel.BackgroundColor = [0 0.75 0]; % green
                case "Moderate"
                    app.AirQualityLabel.BackgroundColor = [0.9290 0.6940 0.1250]; % yellow
                case "Unhealthy for sensitive groups"
                    app.AirQualityLabel.BackgroundColor = [0.8500 0.3250 0.0980]; %orange/red
                case "Unhealthy"
                    app.AirQualityLabel.BackgroundColor = [0.75 0 0];  % red
                case "Very unhealthy"
                    app.AirQualityLabel.BackgroundColor = [0.6350 0.0780 0.1840];  % dark red
                case "Hazardous"
                    app.AirQualityLabel.BackgroundColor = [0.70 0.0780 0.1840];  % dark red
            end
            
        end
        
        function ReadPredictForecastData(app)
            % Get the user input
            value = string(app.LocationCityBostonEditField.Value);
            
            % Reset the display
            resetAppVisuals(app)
            
            % Wait bar
            d = uiprogressdlg(app.AirQualityAppUIFigure,...
                'Title','Please wait','Message','Updating...');
            
            % Read data from web API and predict
            try                
                [app.Label,~,~,~,app.currentData,app.forecastData] = CurrentAirQual(value);
                
                % Display result, use color to indicate air quality
                app.AirQualityLabel.Text = "Air Quality: "+app.Label;
                LabelColor(app);
                
                % Visualize
                plotForecast(app);
                
                % Update table showing current weather info
                app.UITable.Visible = "on";
                app.UITable.Data = app.currentData(:,["T","P",...
                    "DP","RH","WindDir","WindSpd"]);
                app.UITable.ColumnName = ["Temperature","Pressure","DewPoint",...
                    "RelativeHumidity","WindDir","WindSpd"];
            catch
                errordlg("Error for location, try again")
            end
            
            close(d);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % App which gives current and forecasted weather conditions and
            % air quality prediction
            % The current weather info is read from a Python modle (weather.py)
            % and air quality prediction is calculated in MATLAB based on
            % this data
                        
            app.UITable.Visible = "off";
            
        end

        % Value changed function: LocationCityBostonEditField
        function LocationCityBostonEditFieldValueChanged(app, event)
            % Read data from API, predict air quality
            ReadPredictForecastData(app);
            
        end

        % Value changed function: UpdateButton
        function UpdateButtonValueChanged(app, event)
            % Read data from API, predict air quality
            ReadPredictForecastData(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AirQualityAppUIFigure = uifigure('Visible', 'off');
            app.AirQualityAppUIFigure.Name = 'AirQualityApp';
            app.AirQualityConditionsLabel = uilabel(app.AirQualityAppUIFigure);
            app.AirQualityConditionsLabel.Text = 'Air Quality Conditions';
            app.AirQualityLabel = uilabel(app.AirQualityAppUIFigure);
            app.AirQualityLabel.Text = 'Air Quality: ';
            app.DayForecastPanel = uipanel(app.AirQualityAppUIFigure);
            app.DayForecastPanel.Title = '10 - Day Forecast';
            app.CurrentConditionsPanel = uipanel(app.AirQualityAppUIFigure);
            app.CurrentConditionsPanel.Title = 'Current Conditions';
            app.LocationCityBostonEditFieldLabel = uilabel(app.CurrentConditionsPanel);
            app.LocationCityBostonEditFieldLabel.Text = 'Location: City (Boston)';
            app.LocationCityBostonEditField = uieditfield(app.CurrentConditionsPanel, 'text');
            app.LocationCityBostonEditField.ValueChangedFcn = createCallbackFcn(app, @LocationCityBostonEditFieldValueChanged, true);
            app.UpdateButton = uibutton(app.CurrentConditionsPanel, 'state');
            app.UpdateButton.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonValueChanged, true);
            app.UpdateButton.Text = 'Update';
            app.UITable = uitable(app.CurrentConditionsPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.AirQualityAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AirQuality

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AirQualityAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AirQualityAppUIFigure)
        end
    end
end